classdef Main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        QuadcopterSimulationLabel    matlab.ui.control.Label
        Simulation1DButton           matlab.ui.control.Button
        Simulation2DButton           matlab.ui.control.Button
        Simulation3DButton           matlab.ui.control.Button
        Simulation3DbykeybordButton  matlab.ui.control.Button
    end


    properties (Access = private)
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'Main';
            addpath('3D');
        end

        % Button pushed function: Simulation3DButton
        function Simulation3DButtonPushed(app, event)
            Simulate();
        end

        % Button pushed function: Simulation2DButton
        function Simulation2DButtonPushed(app, event)
            app2();
        end

        % Button pushed function: Simulation3DbykeybordButton
        function Simulation3DbykeybordButtonPushed(app, event)
            qn=1;
            t=@KeysTraj;
            optimization = 7;
            Data=[0 0 0];
            assignin('base','Data',Data);
            h_fig=figure('KeyReleaseFcn',@KeyUp);
            h_3d = gca;
            axis equal
            grid on
            view(3);
            xlabel('x [m]'); ylabel('y [m]'); zlabel('z [m]')
            set(gcf,'Renderer','OpenGL')
            
            hold on
            try
                QP = Run_Keyboard_control(h_3d,t,1,0,0,0);
            catch
            end

            hold off
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.QuadcopterSimulationLabel = uilabel(app.UIFigure);
            app.QuadcopterSimulationLabel.Text = 'Quadcopter Simulation';
            app.Simulation1DButton = uibutton(app.UIFigure, 'push');
            app.Simulation1DButton.Text = 'Simulation 1D';
            app.Simulation2DButton = uibutton(app.UIFigure, 'push');
            app.Simulation2DButton.ButtonPushedFcn = createCallbackFcn(app, @Simulation2DButtonPushed, true);
            app.Simulation2DButton.Text = 'Simulation 2D';
            app.Simulation3DButton = uibutton(app.UIFigure, 'push');
            app.Simulation3DButton.ButtonPushedFcn = createCallbackFcn(app, @Simulation3DButtonPushed, true);
            app.Simulation3DButton.Text = 'Simulation 3D';
            app.Simulation3DbykeybordButton = uibutton(app.UIFigure, 'push');
            app.Simulation3DbykeybordButton.ButtonPushedFcn = createCallbackFcn(app, @Simulation3DbykeybordButtonPushed, true);
            app.Simulation3DbykeybordButton.Text = 'Simulation 3D by keybord';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Main

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end