classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        UIAxes          matlab.ui.control.UIAxes
        SimulateButton  matlab.ui.control.Button
        StopButton      matlab.ui.control.Button
    end


    properties (Access = private)
        Data 
        qn
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            axis(app.UIAxes,'equal')
            app.UIAxes.ZGrid = 'on';
            view(app.UIAxes,3);
            app.qn=0;
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            app.qn=1;

            t=@trajectory_generator;
            optimization = 7;
            app.Data=[0 0 0;0 0 1];
            assignin('base','Data',app.Data);
            h_fig=figure('KeyPressFcn',@KeyPress,'KeyReleaseFcn',@KeyUp);
            h_3d = gca;
            axis equal
            grid on
            view(3);
            xlabel('x [m]'); ylabel('y [m]'); zlabel('z [m]')
            set(gcf,'Renderer','OpenGL')
            
            hold on
            %set(h_fig,'WindowKeyPressFcn',@KeyDown, 'WindowKeyReleaseFcn', @KeyUp); 
            %h_fig=plot3(app.Data(end,1),app.Data(end,2),app.Data(end,3));
            try
              QP = Run_Keyboard_control(h_3d,t,app.qn,app.Data(:,:),1,optimization);  
               
            catch
                try
                QP = Run_Keyboard_control(h_3d,t,1,0,0,0);
                catch
                end
            end
            hold off
%             try
%                 QP{app.qn}.TruncateHist();
%                 % Plot position for each quad
%                 h_pos{app.qn} = figure('Name', ['Quad ' num2str(app.qn) ' : position']);
%                 plot_state(h_pos{app.qn}, QP{app.qn}.state_hist(1:3,:), QP{app.qn}.time_hist, 'pos', 'vic');
%                 plot_state(h_pos{app.qn}, QP{app.qn}.state_des_hist(1:3,:), QP{app.qn}.time_hist, 'pos', 'des');
%                 % Plot velocity for each quad
%                 h_vel{app.qn} = figure('Name', ['Quad ' num2str(app.qn) ' : velocity']);
%                 plot_state(h_vel{app.qn}, QP{app.qn}.state_hist(4:6,:), QP{app.qn}.time_hist, 'vel', 'vic');
%                 plot_state(h_vel{app.qn}, QP{app.qn}.state_des_hist(4:6,:), QP{app.qn}.time_hist, 'vel', 'des');
%             catch
%             end
           %app.StopButtonPushed();
           
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
        delete(app)

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.SimulateButton = uibutton(app.UIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app3

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end