classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        AltitudedesiredSliderLabel  matlab.ui.control.Label
        AltitudedesiredSlider       matlab.ui.control.Slider
        UIAxes                      matlab.ui.control.UIAxes
        StartButton                 matlab.ui.control.Button
        UIAxes2                     matlab.ui.control.UIAxes
        KvEditFieldLabel            matlab.ui.control.Label
        KvEditField                 matlab.ui.control.NumericEditField
        KpEditFieldLabel            matlab.ui.control.Label
        KpEditField                 matlab.ui.control.NumericEditField
        ControllerParametreLabel    matlab.ui.control.Label
    end


    properties (Access = public)
        z_des = 1; % Description
    end


    methods (Access = private)

        % Value changed function: AltitudedesiredSlider
        function AltitudedesiredSliderValueChanged(app, event)
app.z_des = app.AltitudedesiredSlider.Value;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            
myworld=vrview('support/asbQuadcopterWorld.wrl');
%mynodes = get(myworld, 'Nodes');
quadcopter_body = myworld.QuadBody;
quadcopter_body.translation=[57 0 88];


% Given trajectory generator
trajhandle = @(t) fixed_set_point(t, app.z_des);

% This is your controller
controlhandle = @controller;

addpath('utils');

video = false;
video_filename = 'height_control.avi';

params = sys_params;

% real-time
real_time = true;

%% **************************** FIGURES *****************************
disp('Initializing figures...')
if video
  video_writer = VideoWriter(video_filename, 'Uncompressed AVI');
  open(video_writer);
end


h_3d = app.UIAxes;
app.UIAxes.View=[0,0];
%app.UIAxes.CameraPosition = [0 -5 0];
axis(app.UIAxes,'equal')

h_2d = app.UIAxes2;
plot_2d = plot(h_2d, 0, 0);
grid on;
xlabel('t [s]'); ylabel('z [m]');

quadcolors = lines(1);

set(gcf,'Renderer','OpenGL')

%% *********************** INITIAL CONDITIONS ***********************
max_iter  = 500;       % max iteration
starttime = 0;         % start of simulation in seconds
tstep     = 0.01;      % this determines the time step at which the solution is given
cstep     = 0.05;      % image capture time interval
nstep     = cstep/tstep;
time      = starttime; % current time
% Get start and stop position
des_start = trajhandle(0);
des_stop  = trajhandle(inf);
stop_pos  = des_stop(1);
x0        = des_start;
xtraj     = nan(max_iter*nstep, length(x0));
ttraj     = nan(max_iter*nstep, 1);

x         = x0;        % state

pos_tol   = 0.01;
vel_tol   = 0.01;

kv=app.KvEditField.Value;
kp=app.KpEditField.Value;

%% ************************* RUN SIMULATION *************************
disp('Simulation Running....')
% Main loop
for iter = 1:max_iter

  timeint = time:tstep:time+cstep;

  tic;
  % Initialize quad plot
  if iter == 1
    h_3d
    quad_state = simStateToQuadState(x0);
    quadcopter_body.translation=[57 quad_state(3) 88];
    QP = QuadPlot(1, quad_state, params.arm_length, 0.05, quadcolors(1,:), max_iter, h_3d);
    quad_state = simStateToQuadState(x);
    QP.UpdateQuadPlot(quad_state, time);
    h_title = title(h_3d, sprintf('iteration: %d, time: %4.2f', iter, time));
  end
  %------------------------------------------------------------------------------------------------------
  % Run simulation
  [tsave, xsave] = ode45(@(t,s) sys_eom(t, s, controlhandle, trajhandle, params,kv,kp), timeint, x);
  x = xsave(end, :)';
   %--------------------------------------------------------------------------------------------------
  
  % Save to traj
  xtraj((iter-1)*nstep+1:iter*nstep,:) = xsave(1:end-1,:);
  ttraj((iter-1)*nstep+1:iter*nstep) = tsave(1:end-1);

  % Update quad plot
  h_3d
  quad_state = simStateToQuadState(x);
  quadcopter_body.translation=[57 quad_state(3) 88];
  QP.UpdateQuadPlot(quad_state, time + cstep);
  set(h_title, 'String', sprintf('iteration: %d, time: %4.2f', iter, time + cstep))
  time = time + cstep; % Update simulation time

  set(plot_2d, 'XData', ttraj(1:iter*nstep), 'YData', xtraj(1:iter*nstep,1));
  if video
    writeVideo(video_writer, getframe(h_fig));
  end

  t = toc;
  % Check to make sure ode45 is not timing out
  if(t > cstep*50)
    err = 'Ode solver took too long for a step. Maybe the controller is unstable.';
    disp(err);
    break;
  end

  % Pause to make real-time
  if real_time && (t < cstep)
    pause(cstep - t);
  end

end
% Check termination criteria
if norm(stop_pos - x(1)) < pos_tol && norm(x(2)) < vel_tol
  err = [];
else
  err = 'Did not converge';
end

disp('Simulation done');
if video
  close(video_writer);
end

if ~isempty(err)
  disp(['Error: ', err]);
end
t_out = ttraj(1:iter*nstep);
z_out = xtraj(1:iter*nstep,1);

% % Sample code to get more info on the response
 sim_info = lsiminfo(z_out, t_out, app.z_des);
%  disp(['Settling time [s]: ', num2str(sim_info.SettlingTime)]);
%  disp(['Overshoot [%]: ', num2str(max(0,(sim_info.Max-app.z_des)*100))]);
stepinfo(z_out,t_out)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.AltitudedesiredSliderLabel = uilabel(app.UIFigure);
            app.AltitudedesiredSliderLabel.Text = 'Altitude desired';
            app.AltitudedesiredSlider = uislider(app.UIFigure);
            app.AltitudedesiredSlider.Limits = [0 20];
            app.AltitudedesiredSlider.ValueChangedFcn = createCallbackFcn(app, @AltitudedesiredSliderValueChanged, true);
            app.AltitudedesiredSlider.Value = 1;
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Drone')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Z')
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Step response')
            xlabel(app.UIAxes2, 'Time')
            ylabel(app.UIAxes2, 'Z')
            app.UIAxes2.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.KvEditFieldLabel = uilabel(app.UIFigure);
            app.KvEditFieldLabel.Text = 'Kv';
            app.KvEditField = uieditfield(app.UIFigure, 'numeric');
            app.KvEditField.Value = 1;
            app.KpEditFieldLabel = uilabel(app.UIFigure);
            app.KpEditFieldLabel.Text = 'Kp';
            app.KpEditField = uieditfield(app.UIFigure, 'numeric');
            app.KpEditField.Value = 1;
            app.ControllerParametreLabel = uilabel(app.UIFigure);
            app.ControllerParametreLabel.Text = 'Controller Parametre:';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end