classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        UIAxes                 matlab.ui.control.UIAxes
        UIAxes2                matlab.ui.control.UIAxes
        UIAxes2_2              matlab.ui.control.UIAxes
        UIAxes2_3              matlab.ui.control.UIAxes
        TrajectoryButtonGroup  matlab.ui.container.ButtonGroup
        LineButton             matlab.ui.control.RadioButton
        SineButton             matlab.ui.control.RadioButton
        SimulateButton         matlab.ui.control.Button
    end


    properties (Access = public)
        Trajectory=@traj_line % Trajectory
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath('utils');
            addpath('trajectories');
            axis(app.UIAxes,'equal')
            view(app.UIAxes,90,0);
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            if(app.LineButton.Value)
                app.Trajectory=@traj_line;
            end
            if(app.SineButton.Value)
                app.Trajectory=@traj_sine;
            end
            
            myworld=vrview('support/asbQuadcopterWorld.wrl');
            %mynodes = get(myworld, 'Nodes');
            quadcopter_body = myworld.QuadBody;
            quadcopter_body.translation=[57 0 88];
            
            controlhandle = @controller;   %add user options for Kv kp
            trajhandle=app.Trajectory;
            
            MainPlot = app.UIAxes;
            splot1 = app.UIAxes2;
            splot2 = app.UIAxes2_2;
            splot3 = app.UIAxes2_3;


params = sys_params;

% real-time
real_time = true;

%% **************************** FIGURES *****************************
disp('Initializing figures...')

quadcolors = lines(1);

set(gcf,'Renderer','OpenGL')

%% *********************** INITIAL CONDITIONS ***********************
t_total  = 7;             % Total simulated time
tstep    = 0.01;          % this determines the time step at which the solution is given
cstep    = 0.05;          % image capture time interval
max_iter = t_total/cstep; % max iteration
nstep    = cstep/tstep;
time     = 0; % current time
err = []; % runtime errors
% Get start and stop position
des_start = trajhandle(0,[]);
des_stop  = trajhandle(inf,[]);

% Get boundary
d_state = nan(max_iter,2);
for iter = 1:max_iter
    dd = trajhandle(cstep*iter,[]);
    d_state(iter,:) = dd.pos(1:2)';
end
y_lim = [min(d_state(:,1)) - 0.1, max(d_state(:,1)) + 0.1];
z_lim = [min(d_state(:,2)) - 0.1, max(d_state(:,2)) + 0.1];
if(4*(z_lim(2) - z_lim(1)) < y_lim(2) - y_lim(1))
    z_lim(1) = z_lim(1) - (y_lim(2) - y_lim(1))/8;
    z_lim(2) = z_lim(2) + (y_lim(2) - y_lim(1))/8;
end
stop_pos = des_stop.pos;
x0        = [des_start.pos; 0; des_start.vel; 0];
xtraj     = nan(max_iter*nstep, length(x0));
ttraj     = nan(max_iter*nstep, 1);

x         = x0;        % state

pos_tol = 0.01;
vel_tol = 0.02;
ang_tol = 0.05;

%% ************************* RUN SIMULATION *************************
disp('Simulation Running....')
% Main loop
for iter = 1:max_iter

  timeint = time:tstep:time+cstep;

  tic;
  % Initialize quad plot
  if iter == 1
    MainPlot
    quad_state = simStateToQuadState(x0);
    quadcopter_body.translation=[quad_state(2) quad_state(3) 88];
    QP = QuadPlot(1, quad_state, params.arm_length, 0.05, quadcolors(1,:), max_iter, MainPlot);
    MainPlot.YLim=y_lim; MainPlot.ZLim=z_lim;
    quad_state = simStateToQuadState(x);
    QP.UpdateQuadPlot(quad_state, time);
    h_title = title(MainPlot, sprintf('iteration: %d, time: %4.2f', iter, time));
  end

  % Run simulation
  [tsave, xsave] = ode45(@(t,s) sys_eom(t, s, controlhandle, trajhandle, params), timeint, x);
  x = xsave(end, :)';

  % Save to traj
  xtraj((iter-1)*nstep+1:iter*nstep,:) = xsave(1:end-1,:);
  ttraj((iter-1)*nstep+1:iter*nstep) = tsave(1:end-1);

  % Update quad plot
   MainPlot
  quad_state = simStateToQuadState(x);
  quadcopter_body.translation=[quad_state(2) quad_state(3) 88];
  quadcopter_body.rotation=[0 0 1 x(3)];
  QP.UpdateQuadPlot(quad_state, time + cstep);
  MainPlot.YLim=y_lim; MainPlot.ZLim=z_lim;
  set(h_title, 'String', sprintf('iteration: %d, time: %4.2f', iter, time + cstep))
  time = time + cstep; % Update simulation time
  
    splot1
    plot(splot1,ttraj(1:iter*nstep), xtraj(1:iter*nstep,1));
    %splot1.XLabel('t [s]'); splot1.YLabel('y [m]');
    grid on;
    splot2
    plot(splot2,ttraj(1:iter*nstep), xtraj(1:iter*nstep,2));
    %splot2.XLabel('t [s]'); splot2.YLabel('z [m]');
    grid on;
    splot3
    plot(splot3,ttraj(1:iter*nstep), 180/pi*xtraj(1:iter*nstep,3));
    grid on;
    %splot3.XLabel('t [s]'); splot3.YLabel('\phi [deg]');

  t = toc;
  % Check to make sure ode45 is not timing out
  if(t > cstep*50)
    err = 'Ode45 Unstable';
    break;
  end

  % Pause to make real-time
  if real_time && (t < cstep)
    pause(cstep - t);
  end

  % Check termination criteria
  if norm(x(1:2) - stop_pos) < pos_tol && norm(x(4:5)) < vel_tol && abs(x(3)) < ang_tol
    err = [];
    break
  end
  err = 'Did not reach goal';
end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Drone')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'y(t)')
            xlabel(app.UIAxes2, 'Time')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes2.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes2_2 = uiaxes(app.UIFigure);
            title(app.UIAxes2_2, 'z(t)')
            xlabel(app.UIAxes2_2, 'Time')
            ylabel(app.UIAxes2_2, 'Z')
            app.UIAxes2_2.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes2_3 = uiaxes(app.UIFigure);
            title(app.UIAxes2_3, '\phi(t)')
            xlabel(app.UIAxes2_3, 'Time')
            ylabel(app.UIAxes2_3, '\phi')
            app.UIAxes2_3.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.TrajectoryButtonGroup = uibuttongroup(app.UIFigure);
            app.TrajectoryButtonGroup.Title = 'Trajectory';
            app.LineButton = uiradiobutton(app.TrajectoryButtonGroup);
            app.LineButton.Text = 'Line';
            app.LineButton.Value = true;
            app.SineButton = uiradiobutton(app.TrajectoryButtonGroup);
            app.SineButton.Text = 'Sine';
            app.SimulateButton = uibutton(app.UIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end